classdef answer_key < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        CloseButton     matlab.ui.control.Button
        AnswerKeyLabel  matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, image_index)
            app.MainApp = mainapp;
            
            %imports image function mapping
            global funcStruct
            run('function_list.m');
            funcStruct = funcStruct;
            

            %Extracts list of image names
            files = dir('Images\');
            FileNames = {files.name};
            global FileNameMat
            FileNameMat = strings;
            for i=1:length(FileNames)
                FileNameMat(i)=append('Images/',string(FileNames(i)));
            end
            FileNameMat(1) = [];
            FileNameMat(1) = [];

            % Optional randomization
            % rng(42); % Fixed seed for reproducibility
            % perm = randperm(length(FileNameMat));
            % FileNameMat = FileNameMat(perm);

            % Initiates photo and axis
            mapping = funcStruct(FileNameMat(image_index));
            Z = imread(mapping{2});
            X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
            Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
            I = imagesc(app.UIAxes,X,Y,Z);
            app.UIAxes.Colormap = jet(100); % Any number of colors should work
            axis(app.UIAxes,'tight')
            % colorbar(app.UIAxes)


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.MainApp.ShowanswerButton.Enable = "on";
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AnswerKeyLabel = uilabel(app.UIFigure);
            app.AnswerKeyLabel.Text = 'Answer Key: ';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = answer_key(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end