classdef mammology_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AddNewCaseButton                matlab.ui.control.Button
        PrevUnansweredButton            matlab.ui.control.Button
        NextUnansweredButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        RestoreProgressButton           matlab.ui.control.Button
        BenignButton                    matlab.ui.control.Button
        CaseNoLabel                     matlab.ui.control.Label
        ImageNo                         matlab.ui.control.Label
        AnswerLabel                     matlab.ui.control.Label
        ShowanswerButton                matlab.ui.control.Button
        username_confirm                matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        SaveProgressButton              matlab.ui.control.Button
        ConfirmButton                   matlab.ui.control.Button
        ClicktheabnormalityorclickbenignLabel  matlab.ui.control.Label
        PleaseloginbeforestartingLabel  matlab.ui.control.Label
        PreviousImageButton             matlab.ui.control.Button
        NextImageButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        UploadedFile  % full path to the file the user picks
    end
    
    methods (Access = private)
        
        function color = indicatorColor(~, text)
            if endsWith(text, 'true')
                color = '0.39,0.83,0.07';
            elseif endsWith(text, 'false')
                color = '1.00,0.00,0.00';
            else 
                color = '0.07,0.62,1.00';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                %imports image function mapping
                global funcStruct
                run('function_list.m');
                funcStruct = funcStruct;
                
                %extracts a category count
                global categoryMap
                categoryMap = containers.Map();
                keySet = keys(funcStruct);
                for i = 1:length(keySet)
                    k = keySet{i};
                    v = funcStruct(k);
                    for j = 1:length(v{3})
                    categoryMap(v{3}{j}) = 0;
                    end
                end

                global totalMap
                totalMap = containers.Map(keys(categoryMap), values(categoryMap));
    
    
                %Extracts list of image names
                files = dir('Images\');
                FileNames = {files.name};
                global FileNameMat
                FileNameMat = strings;
                for i=1:length(FileNames)
                    FileNameMat(i)=append('Images/',string(FileNames(i)));
                end
                FileNameMat(1) = [];
                FileNameMat(1) = [];
    
                % Optional randomization
                % rng(42); % Fixed seed for reproducibility
                % perm = randperm(length(FileNameMat));
                % FileNameMat = FileNameMat(perm);
                % 


                %Creates a table of user information to be stored
                %Stored in order: username, date, totalscore, image 1 score, image 2, ..., image n score
                %images are ordered as they are in FileNameMat
                %user data will be initialized after log-in 
                global user_data
                global login_indicator
                login_indicator = 0;
                user_data = {'username', 'date','total_score'};  % Initialize user_data with column names
                user_data = [user_data, FileNameMat];  % Append image names as headers
                user_data = [user_data;zeros(1,length(user_data))];
                user_data(end,2) = datetime('today');
                
                global user_data_adjustment
                user_data_adjustment = 3;
                global quiz_length 
                quiz_length= length(user_data)-user_data_adjustment;
    
                % Initiates photo and axis
                Z = imread(FileNameMat(1));
                global image_index
                image_index = 1;
                X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
                Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
                I = imagesc(app.UIAxes,X,Y,Z);
                app.UIAxes.Colormap = jet(100); % Any number of colors should work
                axis(app.UIAxes,'tight')
                % colorbar(app.UIAxes)
    
    
                %case number text
                caseno = FileNameMat(image_index);
                digits = regexp(caseno, '\d', 'match'); % extracts all individual digits
                firstThreeDigits = digits;
                result = strjoin(firstThreeDigits, '');
                set(app.CaseNoLabel, 'Text', 'Case Number: ' + result);
                global pos
                pos = [NaN, NaN];
    
    
                % Turn on datacursor mode in the UIFigure
                dcm = datacursormode(app.UIFigure);
                dcm.Enable           = 'on';
                dcm.DisplayStyle     = 'datatip';   % <-- NO in‐place circle
                dcm.SnapToDataVertex = 'off';      % show exactly where you clicked
                dcm.UpdateFcn        = @(~,evt) localDcmFcn(evt, app);
                % Define a custom UpdateFcn function
                function txt = localDcmFcn(evt, app)
                    % 1) build the little text you want
                    pos = evt.Position;
                    txt = {
                      sprintf('X: %.2f', pos(1)), ...
                      sprintf('Y: %.2f', pos(2))
                    };
                
                    % 2) plot your custom marker
                    %    we delete any old custom marker first
                    delete(findall(app.UIAxes, 'Tag','MyCustomMarker'))
                
                    hold(app.UIAxes,'on')
                    scatter(app.UIAxes, ...
                        pos(1), pos(2), ...        % location
                        200, ...                    % size (pts^2)
                        'y', ...                    % fill color
                        'filled', ...
                        'MarkerEdgeColor','k', ...  % black border
                        'Tag','MyCustomMarker' )    % so we can delete it next click
                    hold(app.UIAxes,'off')

                end

                index_text = "Image " + image_index + "/" + length(FileNameMat);
                set(app.ImageNo, 'Text', index_text)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                
                % Get the coordinates of the mouse click relative to the axes
                [x,y] = ginput(1);
                % Display the coordinates in the Command Window
                set(app.PleaseloginbeforestartingLabel, 'Text', ['X: ', num2str(x), ', Y: ', num2str(y)]);  
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
                global image_index
                global FileNameMat
                global pos
                global user_data
                global user_data_adjustment
                global categoryMap
                global totalMap
                user_data_adjustment = 3;
                %set(app.PleaseloginbeforestartingLabel, 'Text', ['X: ', num2str(pos(1,1)), ', Y: ', num2str(pos(1,2))]);
                global funcStruct
                global login_indicator
                    mapping = funcStruct(FileNameMat(image_index));
                conditional = mapping{1};
                isCorrect = conditional(pos(1,1), pos(1,2));
                if login_indicator == 1
                    if user_data(end,image_index+user_data_adjustment) == '0'
                        if isnan(pos)
                            set(app.PleaseloginbeforestartingLabel, 'Text', 'Please Select an Answer');
                        else
                            set(app.PleaseloginbeforestartingLabel, 'Text', '');
                            for i=1:length(mapping{3})
                                totalMap(mapping{3}{i}) = totalMap(mapping{3}{i}) + 1;
                            end
                            if isCorrect
                                set(app.PleaseloginbeforestartingLabel,'Text', 'Nice!');
                                set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.47,0.67,0.19');
                                user_data(end,user_data_adjustment) = str2double(user_data(end,user_data_adjustment)) + 1;
                                for i=1:length(mapping{3})
                                    categoryMap(mapping{3}{i}) = categoryMap(mapping{3}{i}) + 1;
                                end
                            else
                                set(app.PleaseloginbeforestartingLabel, 'Text', 'Nope!');
                                set(app.PleaseloginbeforestartingLabel, 'FontColor', '1.00,0.00,0.00');
                            end
                            %Show answer
                            answer_key(app, image_index)
                            % Update the user_data table to store the result for this image
                            cats = string(mapping{3});
                            cat_str = strjoin(cats, ', ');
                            entry   = cat_str + ", " + string(isCorrect);
                            user_data(end, image_index + user_data_adjustment) = entry;  % Store the click result (1 or 0)
                            set(app.Label, 'BackgroundColor', indicatorColor(app, user_data(end, image_index + user_data_adjustment)))
                        end
                    else
                        set(app.PleaseloginbeforestartingLabel, 'Text', 'You have already answered this question');
                    end
                else
                    beep on
                    beep
                    set(app.username_confirm, 'BackgroundColor', '1.00,0.00,0.00');
                end
        end

        % Button pushed function: NextImageButton
        function NextImage(app, event)
                global image_index
                global FileNameMat
                global user_data
                global user_data_adjustment
                global quiz_length
                global pos
                pos = [NaN, NaN];
                image_index = image_index+1;
                if image_index > quiz_length
                    image_index = image_index -1;
                    beep on
                    beep
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'You are at the end of the assessment.');
                else
                    Z = imread(FileNameMat(image_index));
                    X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
                    Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
                    I = imagesc(app.UIAxes,X,Y,Z);
                    index_text = "Image " + image_index + "/" + length(FileNameMat);
                    set(app.ImageNo, 'Text', index_text)
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'Your result will display here');
                    ansText = user_data(end,image_index+user_data_adjustment);
                    if ansText == '0'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Your result will display here');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.00,0.00,0.00');
                    elseif endsWith(ansText,'true')
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nice!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.47,0.67,0.19');
                    elseif endsWith(ansText,'false')
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nope!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '1.00,0.00,0.00');
                    end
                    caseno = FileNameMat(image_index);
                    digits = regexp(caseno, '\d', 'match'); % extracts all individual digits
                    firstThreeDigits = digits;
                    result = strjoin(firstThreeDigits, '');
                    set(app.CaseNoLabel, 'Text', 'Case Number: ' + result);
                    set(app.Label, 'BackgroundColor', indicatorColor(app, user_data(end, image_index + user_data_adjustment)))
                end
                    
        end

        % Button pushed function: PreviousImageButton
        function PrevImage(app, event)
                global image_index
                global FileNameMat
                global user_data
                global user_data_adjustment
                global pos
                pos = [NaN, NaN];
                image_index = image_index-1;
                if image_index < 1
                    image_index = image_index +1;
                    beep on
                    beep
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'You are at the beginning of the assessment.');
                else
                    Z = imread(FileNameMat(image_index));
                    X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
                    Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
                    I = imagesc(app.UIAxes,X,Y,Z);
                    index_text = "Image " + image_index + "/" + length(FileNameMat);
                    set(app.ImageNo, 'Text', index_text)
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ');
                    ansText = user_data(end,image_index+user_data_adjustment);
                    if ansText == '0'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Your result will display here');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.00,0.00,0.00');
                    elseif endsWith(ansText,'true')
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nice!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.47,0.67,0.19');
                    elseif endsWith(ansText,'false')
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nope!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '1.00,0.00,0.00');
                    end

                    %case number text
                    caseno = FileNameMat(image_index);
                    digits = regexp(caseno, '\d', 'match'); % extracts all individual digits
                    firstThreeDigits = digits;
                    result = strjoin(firstThreeDigits, '');
                    set(app.CaseNoLabel, 'Text', 'Case Number: ' + result);
                    set(app.Label, 'BackgroundColor', indicatorColor(app, user_data(end, image_index + user_data_adjustment)))
                end
        end

        % Button pushed function: LoginButton
        function LogIn(app, event)
                global user_data
                user = app.UsernameEditField.Value;
                user_data(2,1)={user};
                set(app.username_confirm, 'Text', strcat(user_data(end,1),' logged in!'))
                set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ')
                set(app.PleaseloginbeforestartingLabel, 'BackgroundColor', 'none')
                global login_indicator
                login_indicator = 1;
        end

        % Button pushed function: SaveProgressButton
        function SaveProgressButtonPushed(app, event)
                global user_data
                global categoryMap
                global login_indicator
                global totalMap
                global quiz_length

                if login_indicator == 0
                    beep on
                    beep
                    set(app.username_confirm, 'BackgroundColor', '1.00,0.00,0.00');
                else
                    unanswered = 0;
                    total = 0;
                    for i=4:size(user_data,2)
                        if endsWith(user_data(2,i), 'true')
                            total = total + 1;
                        end
                        if strcmp(user_data(2,i), '0')
                            unanswered = unanswered + 1;
                        end
                    end
                    user_data(2,3) = total;
                    keysList = keys(categoryMap);
                    valuesList = values(categoryMap);
                    tempMap = containers.Map(keysList, valuesList);
                    tempMap('total') = total;
                    totalMap('total') = quiz_length;
                    totalMap('unanswered') = unanswered;
                    relative_directory = 'results';
                    if ~exist(relative_directory, 'dir')
                        mkdir(relative_directory);
                    end
                    output_file = fullfile(relative_directory, strcat(user_data(2,1), '_results.xlsx'));
                    writematrix(user_data, output_file)
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'Exam finished and results saved, you may exit the app. ');
                    set(app.PleaseloginbeforestartingLabel, 'BackgroundColor', '0.00,1.00,0.00');
                    results_screen(app, tempMap, totalMap)
                end

        end

        % Button pushed function: ShowanswerButton
        function ShowanswerButtonPushed(app, event)
                global image_index
                global FileNameMat
                global pos
                global user_data
                global user_data_adjustment
                global funcStruct
                global login_indicator
  
               
                if login_indicator == 1
                    if user_data(end,image_index+user_data_adjustment) == '0'
                        set(app.AnswerLabel, 'Text', 'Please Answer Question First.');
                    else
                        answer_key(app, image_index)
                    end
                end
        end

        % Button pushed function: BenignButton
        function BenignButtonPushed(app, event)
                global image_index
                global FileNameMat
                global pos
                global user_data
                global user_data_adjustment
                global categoryMap
                global totalMap
                user_data_adjustment = 3;
                %set(app.PleaseloginbeforestartingLabel, 'Text', ['X: ', num2str(pos(1,1)), ', Y: ', num2str(pos(1,2))]);
                global funcStruct
                global login_indicator
                    mapping = funcStruct(FileNameMat(image_index));
                conditional = mapping{1};
                isCorrect = conditional(Inf, Inf);
                if login_indicator == 1
                    if user_data(end,image_index+user_data_adjustment) == '0'
                        set(app.PleaseloginbeforestartingLabel, 'Text', '');
                        for i=1:length(mapping{3})
                            totalMap(mapping{3}{i}) = totalMap(mapping{3}{i}) + 1;
                        end
                        % identical to confirm past here
                        if isCorrect
                            set(app.PleaseloginbeforestartingLabel,'Text', 'Nice!');
                            set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.47,0.67,0.19');
                            user_data(end,user_data_adjustment) = str2double(user_data(end,user_data_adjustment)) + 1;
                            for i=1:length(mapping{3})
                                categoryMap(mapping{3}{i}) = categoryMap(mapping{3}{i}) + 1;
                            end
                        else
                            set(app.PleaseloginbeforestartingLabel, 'Text', 'Nope!');
                            set(app.PleaseloginbeforestartingLabel, 'FontColor', '1.00,0.00,0.00');
                        end
                        %Show answer
                        answer_key(app, image_index)
                        % Update the user_data table to store the result for this image
                        cats = string(mapping{3});
                        cat_str = strjoin(cats, ', ');
                        entry   = cat_str + ", " + string(isCorrect);
                        user_data(end, image_index + user_data_adjustment) = entry;  % Store the click result (1 or 0)
                        set(app.Label, 'BackgroundColor', indicatorColor(app, user_data(end, image_index + user_data_adjustment)))
                    else
                        set(app.PleaseloginbeforestartingLabel, 'Text', 'You have already answered this question');
                    end
                else
                    beep on
                    beep
                    set(app.username_confirm, 'BackgroundColor', '1.00,0.00,0.00');
                end
    
    
        end

        % Button pushed function: RestoreProgressButton
        function RestoreProgressButtonPushed(app, event)
        % only show .xlsx files in the dialog
        global user_data
        global user_data_adjustment
        [file, path] = uigetfile( ...
            {'*.xlsx','Excel 97–2003 Workbook (*.xlsx)'}, ...
            'Select an .xlsx file to upload');
        if isequal(file,0)
            % user hit Cancel
            return
        end
        % store it
        app.UploadedFile = fullfile(path, file);
        newData = readcell(app.UploadedFile);
        user_data = string(newData);
        global funcStruct

        %extracts a category count
        global categoryMap
        categoryMap = containers.Map();
        keySet = keys(funcStruct);
        for i = 1:length(keySet)
            k = keySet{i};
            v = funcStruct(k);
            for j = 1:length(v{3})
            categoryMap(v{3}{j}) = 0;
            end
        end

        global totalMap
        totalMap = containers.Map(keys(categoryMap), values(categoryMap));
        for i = 1+user_data_adjustment:length(user_data)
            isCorrect = NaN;
            if endsWith(user_data(end,i), 'true')
                isCorrect = 1;
            elseif endsWith(user_data(end,i), 'false')
                isCorrect = 0;
            end
            txt = string(user_data(end,i));   % or a char vector

            % Split by comma
            parts = strtrim(split(txt, ","));        % string array, trimmed
            
            % Take all except the last element
            categories = parts(1:end-1);
            for j = 1:length(categories)
                if isCorrect == 1
                    categoryMap(categories(j)) = categoryMap(categories(j)) + 1;
                end
                totalMap(categories(j)) = totalMap(categories(j)) + 1;
            end
        
        end


        % feedback to user (optional)
        set(app.RestoreProgressButton, 'Text', 'Progress Restored')

        set(app.username_confirm, 'Text', strcat(user_data(end,1),' logged in!'))
        set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ')
        set(app.PleaseloginbeforestartingLabel, 'BackgroundColor', 'none')
        global login_indicator
        login_indicator = 1;
        global image_index
        global user_data_adjustment
        set(app.Label, 'BackgroundColor', indicatorColor(app, user_data(end, image_index + user_data_adjustment)))
        end

        % Button pushed function: NextUnansweredButton
        function NextUnansweredButtonPushed(app, event)
                global image_index
                global FileNameMat
                global user_data
                global user_data_adjustment
                global pos
                global quiz_length
                pos = [NaN, NaN];
                image_index = image_index+1;
                if image_index > quiz_length
                    temp_color = 'Black';
                else 
                    temp_color = indicatorColor(app,user_data(end, image_index + user_data_adjustment));
                end
                while ~isequaln(temp_color, '0.07,0.62,1.00') && image_index <= quiz_length
                    image_index = image_index+1;
                    if image_index > quiz_length
                        temp_color = 'Black';
                    else 
                        temp_color = indicatorColor(app,user_data(end, image_index + user_data_adjustment));
                    end
                end
                if image_index > quiz_length
                    image_index = quiz_length;
                    beep on
                    beep
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'All further questions answered.');
                else
                    Z = imread(FileNameMat(image_index));
                    X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
                    Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
                    I = imagesc(app.UIAxes,X,Y,Z);
                    index_text = "Image " + image_index + "/" + length(FileNameMat);
                    set(app.ImageNo, 'Text', index_text)
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ');
                    if user_data(end,image_index+user_data_adjustment) == '0'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Your result will display here');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.00,0.00,0.00');
                    elseif user_data(end,image_index+user_data_adjustment) == 'true'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nice!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.47,0.67,0.19');
                    elseif user_data(end,image_index+user_data_adjustment) == 'false'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nope!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '1.00,0.00,0.00');
                    end
                    %case number text
                    caseno = FileNameMat(image_index);
                    digits = regexp(caseno, '\d', 'match'); % extracts all individual digits
                    firstThreeDigits = digits;
                    result = strjoin(firstThreeDigits, '');
                    set(app.CaseNoLabel, 'Text', 'Case Number: ' + result);
                    set(app.Label, 'BackgroundColor', indicatorColor(app, user_data(end, image_index + user_data_adjustment)))
                end
        end

        % Button pushed function: PrevUnansweredButton
        function PrevUnansweredButtonPushed(app, event)
                global image_index
                global FileNameMat
                global user_data
                global user_data_adjustment
                global pos
                pos = [NaN, NaN];
                image_index = image_index-1;
                temp_color = indicatorColor(app,user_data(end, image_index + user_data_adjustment));
                while ~isequaln(temp_color, '0.07,0.62,1.00') && image_index > 0
                    image_index = image_index-1;
                    temp_color = indicatorColor(app,user_data(end, image_index + user_data_adjustment));
                end
                if image_index < 1
                    image_index = 1;
                    beep on
                    beep
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'All prior questions answered.');
                else
                    Z = imread(FileNameMat(image_index));
                    X = linspace(0,20*pi,size(Z,2)); % Important: number of cols of Z
                    Y = linspace(0,20*pi,size(Z,1)); % Important: number of rows of Z
                    I = imagesc(app.UIAxes,X,Y,Z);
                    index_text = "Image " + image_index + "/" + length(FileNameMat);
                    set(app.ImageNo, 'Text', index_text)
                    set(app.PleaseloginbeforestartingLabel, 'Text', 'Click the abnormality, or click benign. ');
                    if user_data(end,image_index+user_data_adjustment) == '0'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Your result will display here');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.00,0.00,0.00');
                    elseif user_data(end,image_index+user_data_adjustment) == 'true'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nice!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '0.47,0.67,0.19');
                    elseif user_data(end,image_index+user_data_adjustment) == 'false'
                        set(app.PleaseloginbeforestartingLabel,'Text', 'Nope!');
                        set(app.PleaseloginbeforestartingLabel, 'FontColor', '1.00,0.00,0.00');
                    end
                    %case number text
                    caseno = FileNameMat(image_index);
                    digits = regexp(caseno, '\d', 'match'); % extracts all individual digits
                    firstThreeDigits = digits;
                    result = strjoin(digits, '');
                    set(app.CaseNoLabel, 'Text', 'Case Number: ' + result);
                    set(app.Label, 'BackgroundColor', indicatorColor(app, user_data(end, image_index + user_data_adjustment)))
                end
        end

        % Button pushed function: AddNewCaseButton
        function AddNewCaseButtonPushed(app, event)
            case_creator()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.NextImageButton = uibutton(app.UIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImage, true);
            app.NextImageButton.Text = 'Next Image';
            app.PreviousImageButton = uibutton(app.UIFigure, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PrevImage, true);
            app.PreviousImageButton.Text = 'Previous Image';
            app.PleaseloginbeforestartingLabel = uilabel(app.UIFigure);
            app.PleaseloginbeforestartingLabel.Text = 'Please log in before starting';
            app.ClicktheabnormalityorclickbenignLabel = uilabel(app.UIFigure);
            app.ClicktheabnormalityorclickbenignLabel.Text = 'Click the abnormality, or click benign';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.SaveProgressButton = uibutton(app.UIFigure, 'push');
            app.SaveProgressButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProgressButtonPushed, true);
            app.SaveProgressButton.Text = 'Save Progress';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LogIn, true);
            app.LoginButton.Text = 'Log in';
            app.username_confirm = uilabel(app.UIFigure);
            app.username_confirm.Text = 'Please type in your JHED';
            app.ShowanswerButton = uibutton(app.UIFigure, 'push');
            app.ShowanswerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowanswerButtonPushed, true);
            app.ShowanswerButton.Text = 'Show answer';
            app.AnswerLabel = uilabel(app.UIFigure);
            app.AnswerLabel.Text = '';
            app.ImageNo = uilabel(app.UIFigure);
            app.ImageNo.Text = 'Image 1';
            app.CaseNoLabel = uilabel(app.UIFigure);
            app.CaseNoLabel.Text = 'Case No: ';
            app.BenignButton = uibutton(app.UIFigure, 'push');
            app.BenignButton.ButtonPushedFcn = createCallbackFcn(app, @BenignButtonPushed, true);
            app.BenignButton.Text = 'Benign';
            app.RestoreProgressButton = uibutton(app.UIFigure, 'push');
            app.RestoreProgressButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreProgressButtonPushed, true);
            app.RestoreProgressButton.Text = 'Restore Progress';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NextUnansweredButton = uibutton(app.UIFigure, 'push');
            app.NextUnansweredButton.ButtonPushedFcn = createCallbackFcn(app, @NextUnansweredButtonPushed, true);
            app.NextUnansweredButton.Text = 'Next Unanswered';
            app.PrevUnansweredButton = uibutton(app.UIFigure, 'push');
            app.PrevUnansweredButton.ButtonPushedFcn = createCallbackFcn(app, @PrevUnansweredButtonPushed, true);
            app.PrevUnansweredButton.Text = 'Prev Unanswered';
            app.AddNewCaseButton = uibutton(app.UIFigure, 'push');
            app.AddNewCaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewCaseButtonPushed, true);
            app.AddNewCaseButton.Text = 'Add New Case';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mammology_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end