classdef results_screen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ByCategoryTextArea       matlab.ui.control.TextArea
        ByCategoryTextAreaLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        TotalScoreLabel          matlab.ui.control.Label
        ResultsLabel             matlab.ui.control.Label
        CloseButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, scores, totals)
            app.MainApp = mainapp;
            set(app.TotalScoreLabel, 'Text', "Total Score: " + string(scores('total'))+"/"+string(totals('total')))
        
            % Category display name lookup
            displayNames = containers.Map;
            displayNames('Density_1') = 'Fatty Density';
            displayNames('Density_2') = 'SF Density';
            displayNames('Density_3') = 'Hetero Density';
            displayNames('Density_4') = 'Extremely Dense';
            displayNames('Baseline_1') = 'Baseline';
            displayNames('Baseline_2') = 'Non-Baseline';
            displayNames('FindingType_1') = 'Asymmetry';
            displayNames('FindingType_2') = 'Mass';
            displayNames('FindingType_3') = 'Distortion';
            displayNames('FindingType_4') = 'Calcification';
            displayNames('FindingType_5') = 'Mass+Calcification';
            displayNames('FindingType_6') = 'Asymmetry+Calcification';
            displayNames('FindingType_7') = 'Node';
            displayNames('FindingType_8') = 'Multiple Masses';
            displayNames('FindingType_9') = 'Distortion+Calcification';
            displayNames('RetroglandularFat_1') = 'Retroglandular Fat';
            displayNames('RetroglandularFat_2') = 'Non Retroglandular Fat';
            displayNames('FindingEdge_1') = 'On Edge';
            displayNames('FindingEdge_2') = 'Not On Edge';
            displayNames('FindingTomo-only_1') = 'Tomo-Only';
            displayNames('FindingTomo-only_2') = 'Not Tomo-Only';
        
            keySet = keys(scores);
            category_text = "Category scores:" + newline;
            for i = 1:length(keySet)
                k = keySet{i};
                v = scores(k);
                if strcmp(k,'total') == 0
                    if isKey(displayNames, k)
                        label = displayNames(k);
                    else
                        label = k;
                    end
                    category_text = category_text + label + ": " + string(v) + '/' + string(totals(k)) + newline;
                end
            end
            category_text = category_text + string(totals('unanswered')) + ' questions unanswered';
            set(app.ByCategoryTextArea, 'Value', category_text)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results: ';
            app.TotalScoreLabel = uilabel(app.UIFigure);
            app.TotalScoreLabel.Text = 'Total Score: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ByCategoryTextAreaLabel = uilabel(app.UIFigure);
            app.ByCategoryTextAreaLabel.Text = 'By Category: ';
            app.ByCategoryTextArea = uitextarea(app.UIFigure);
            app.ByCategoryTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = results_screen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end